classdef Phased_Array < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotButton                     matlab.ui.control.Button
        ApodizationSwitch              matlab.ui.control.Switch
        ApodizationSwitchLabel         matlab.ui.control.Label
        DirectivitySwitch              matlab.ui.control.Switch
        DirectivitySwitchLabel         matlab.ui.control.Label
        NofelementsEditField           matlab.ui.control.NumericEditField
        NofelementsEditFieldLabel      matlab.ui.control.Label
        ElementwidthmmEditField        matlab.ui.control.NumericEditField
        ElementwidthmmEditFieldLabel   matlab.ui.control.Label
        ArraypitchmmEditField          matlab.ui.control.NumericEditField
        ArraypitchmmEditFieldLabel     matlab.ui.control.Label
        FocalpointangleEditField       matlab.ui.control.NumericEditField
        FocalpointangleEditFieldLabel  matlab.ui.control.Label
        FocalpointdistancemmEditField  matlab.ui.control.NumericEditField
        FocalpointdistancemmEditFieldLabel  matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Extracting the user defined parameters for the
            % ultrasonic_phased_array function
            fpd = app.FocalpointdistancemmEditField.Value;
            fpa = app.FocalpointangleEditField.Value;
            N = app.NofelementsEditField.Value;
            pch = app.ArraypitchmmEditField.Value;
            a = app.ElementwidthmmEditField.Value;
            direc = app.DirectivitySwitch.Value;
            apod = app.ApodizationSwitch.Value;
            [total_pressure,x2,y2,x,y,time_delay] = ultrasonic_phased_array(fpd,fpa,N,pch,a,direc,apod);
            % Figure 1 : 2-D Plot
            imagesc(app.UIAxes,x2,y2,abs(total_pressure));
            colormap(app.UIAxes,"hot");
            legend_1 = colorbar(app.UIAxes);
            legend_1.Label.String = "Pressure";
            xlim(app.UIAxes,[-300 300]);
            ylim(app.UIAxes,[0 300]);
            % Figure 2 : 3-D Surface Plot
            light = surf(app.UIAxes_2,x,y,abs(total_pressure));
            colormap(app.UIAxes_2,"hot");
            legend_2 = colorbar(app.UIAxes_2);
            legend_2.Label.String = "Pressure";
            xlim(app.UIAxes_2,[-300 300]);
            ylim(app.UIAxes_2,[0 300]);
            zlabel(app.UIAxes_2,"Pressure");
            set(light,'LineStyle','none');
            view(app.UIAxes_2,220,65);
            % Figure 3 : Transducer delay
            elements = 1:1:N;
            stem(app.UIAxes_3,elements,time_delay);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2-D Plot')
            xlabel(app.UIAxes, 'y (mm)')
            ylabel(app.UIAxes, 'x (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3-D Surface plot')
            xlabel(app.UIAxes_2, 'y (mm)')
            ylabel(app.UIAxes_2, 'x (mm)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Transducer delay')
            xlabel(app.UIAxes_3, 'Element ')
            ylabel(app.UIAxes_3, 'Delay (µs)')
            zlabel(app.UIAxes_3, 'Z')
            app.FocalpointdistancemmEditFieldLabel = uilabel(app.UIFigure);
            app.FocalpointdistancemmEditFieldLabel.Text = 'Focal point distance (mm)';
            app.FocalpointdistancemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocalpointdistancemmEditField.Value = 150;
            app.FocalpointangleEditFieldLabel = uilabel(app.UIFigure);
            app.FocalpointangleEditFieldLabel.Text = 'Focal point angle (°)';
            app.FocalpointangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArraypitchmmEditFieldLabel = uilabel(app.UIFigure);
            app.ArraypitchmmEditFieldLabel.Text = 'Array pitch (mm)';
            app.ArraypitchmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArraypitchmmEditField.Value = 10;
            app.ElementwidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.ElementwidthmmEditFieldLabel.Text = 'Element width (mm)';
            app.ElementwidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElementwidthmmEditField.Value = 10;
            app.NofelementsEditFieldLabel = uilabel(app.UIFigure);
            app.NofelementsEditFieldLabel.Text = 'N° of elements';
            app.NofelementsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NofelementsEditField.Value = 16;
            app.DirectivitySwitchLabel = uilabel(app.UIFigure);
            app.DirectivitySwitchLabel.Text = 'Directivity';
            app.DirectivitySwitch = uiswitch(app.UIFigure, 'slider');
            app.DirectivitySwitch.Items = {'No', 'Yes'};
            app.DirectivitySwitch.Value = 'No';
            app.ApodizationSwitchLabel = uilabel(app.UIFigure);
            app.ApodizationSwitchLabel.Text = 'Apodization';
            app.ApodizationSwitch = uiswitch(app.UIFigure, 'slider');
            app.ApodizationSwitch.Items = {'No', 'Yes'};
            app.ApodizationSwitch.Value = 'No';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Phased_Array

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end